classdef RawConverter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Title                         matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        namethecvsEditField           matlab.ui.control.EditField
        namethecvsEditFieldLabel      matlab.ui.control.Label
        PromptGenotypeEditField       matlab.ui.control.EditField
        PromptGenotypeEditFieldLabel  matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        DIVFORMATCheckBox             matlab.ui.control.CheckBox
        ExportButton                  matlab.ui.control.Button
        LoadButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        RawFileFolder % Description
        dirname
        metadatafilename
        fill_div
        fill_genotype
        genotype 
        System
        ExportFolder
        fs double
        indices
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
           
            app.RawFileFolder = uigetdir('C:\','Select the folder that contains .RAW files to convert');
            cd(app.RawFileFolder);
            listin = dir;
            for i = 3:length(listin)
                FileName{i-2,1} = listin(i).name;
            end
            T = table(FileName);
            app.UITable.Data = T;
            
            %% Set parameters for Axion or Msc
            % Get the file extension 
            file = listin(3).name;
            [filepath,name,ext] = fileparts(file);
            % switch between HD5F5 and RAW
            if strcmp(ext,".raw")
                app.fs = 12500;
                app.System = "Axion";    
            elseif strcmp(ext,".h5")
                app.fs = 10000;
                app.System = "MSC";

            else

            end


        end

        % Value changed function: DIVFORMATCheckBox
        function DIVFORMATCheckBoxValueChanged(app, event)
            app.fill_div = app.DIVFORMATCheckBox.Value;
            if app.fill_div
                app.fill_div ='y';
            else
                app.fill_div = 'n';
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            

                if strcmp(app.System,"Axion") & ~isempty(app.fill_div)

                    app.ExportFolder = uigetdir('C:\','Select the folder where to export the .mat dataset');
                    app.dirname = app.RawFileFolder;
                    ConvertAxionRaw(app.dirname,app.metadatafilename,app.fill_div,'y',app.genotype,app.fs,app.ExportFolder)

                elseif strcmp(app.System,"MSC") & ~isempty(app.fill_div)

                    app.ExportFolder = uigetdir('C:\','Select the folder where to export the .mat dataset');
                    converterMSC(app.RawFileFolder,app.ExportFolder,app.metadatafilename,app.fill_div,'y',app.genotype,app.fs,app.indices)

                elseif isempty(app.fill_div)

                    f = warndlg('Select a valid option for DIV Format','Warning');

                else

                     f = warndlg('The file format is not compatible with the conversion...make sure the folder contains .raw or .h5 files','Warning');

                end


        end

        % Value changed function: namethecvsEditField
        function namethecvsEditFieldValueChanged(app, event)
            app.metadatafilename = app.namethecvsEditField.Value;
            
        end

        % Value changed function: PromptGenotypeEditField
        function PromptGenotypeEditFieldValueChanged(app, event)
            app.genotype = app.PromptGenotypeEditField.Value;
            
        end

        % Callback function
        function UITableSelectionChanged(app, event)
             app.UITable.Selection;
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.indices = event.Indices;
            app.indices = app.indices(:,1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.DIVFORMATCheckBox = uicheckbox(app.UIFigure);
            app.DIVFORMATCheckBox.ValueChangedFcn = createCallbackFcn(app, @DIVFORMATCheckBoxValueChanged, true);
            app.DIVFORMATCheckBox.Text = 'DIV FORMAT';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'File Name'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.PromptGenotypeEditFieldLabel = uilabel(app.UIFigure);
            app.PromptGenotypeEditFieldLabel.Text = 'Prompt Genotype';
            app.PromptGenotypeEditField = uieditfield(app.UIFigure, 'text');
            app.PromptGenotypeEditField.ValueChangedFcn = createCallbackFcn(app, @PromptGenotypeEditFieldValueChanged, true);
            app.namethecvsEditFieldLabel = uilabel(app.UIFigure);
            app.namethecvsEditFieldLabel.Text = 'name the .cvs ';
            app.namethecvsEditField = uieditfield(app.UIFigure, 'text');
            app.namethecvsEditField.ValueChangedFcn = createCallbackFcn(app, @namethecvsEditFieldValueChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo.png';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = {'This App convert  Raw Data into mat files '; 'suitable for MEA-NAP analysis'; 'Create CSV file using the raw filename'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RawConverter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end