classdef FuncConnOpt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        nCheck                    matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        ifcheck                   matlab.ui.control.CheckBox
        probThreshValue           matlab.ui.control.NumericEditField
        probabilisticthresholdingpercentilethresholdLabel  matlab.ui.control.Label
        numRepProbThre            matlab.ui.control.NumericEditField
        probabilisticthresholdingnumberofrepeatsLabel  matlab.ui.control.Label
        AdjMType                  matlab.ui.control.DropDown
        AdjacencyMatrixTypeLabel  matlab.ui.control.Label
        TruncatedLength           matlab.ui.control.NumericEditField
        InsertLenghoftruncatedrecordingLabel  matlab.ui.control.Label
        truncateRec               matlab.ui.control.CheckBox
        LagValues                 matlab.ui.control.EditField
        setthedifferentlagvaluesinmsdefaultto101525Label  matlab.ui.control.Label
        Save                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp    % Main app

        FuncConLagval = [10, 15, 25]; % set the different lag values (in ms), default to [10, 15, 25]
        TruncRec = 0; % truncate recording? 1 = yes, 0 = no
        TruncLength = 120; % length of truncated recordings (in seconds)
        adjMtype = 'weighted'; % 'weighted' or 'binary'
        ProbThreshRepNum = 200; % probabilistic thresholding number of repeats 
        ProbThreshTail = 0.05; % probabilistic thresholding percentile threshold 
        ProbThreshPlotChecks = 1; % randomly sample recordings to plot probabilistic thresholding check, 1 = yes, 0 = no
        ProbThreshPlotChecksN = 5; % number of random checks to plot
            
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, RunMyAnalysis)
            app.CallingApp = RunMyAnalysis;
        end

        % Button pushed function: Save
        function SaveButtonPushed(app, event)
            UpdateFuncConnParams(app.CallingApp, app.FuncConLagval, app.TruncRec,app.TruncLength, app.adjMtype,...
            app.ProbThreshRepNum,app.ProbThreshTail,app.ProbThreshPlotChecks,app.ProbThreshPlotChecksN)
            delete(app)
        end

        % Value changed function: LagValues
        function LagValuesValueChanged(app, event)
            value = app.LagValues.Value;
            app.FuncConLagval = str2double(split(value,';'))';
        end

        % Value changed function: truncateRec
        function truncateRecValueChanged(app, event)
            value = app.truncateRec.Value;
            if value 
                app.TruncatedLength.Visible = "on";
            else
                app.TruncatedLength.Visible = "off";
            end

        end

        % Value changed function: TruncatedLength
        function TruncatedLengthValueChanged(app, event)
            app.TruncRec = app.TruncatedLength.Value;
            
        end

        % Value changed function: ifcheck
        function ifcheckValueChanged(app, event)
            value = app.ifcheck.Value;
            if value 
                app.nCheck.Visible = "on";
            else
                app.nCheck.Visible = "off";
            end   
        end

        % Value changed function: AdjMType
        function AdjMTypeValueChanged(app, event)
            app.adjMtype = app.AdjMType.Value;
            
        end

        % Value changed function: numRepProbThre
        function numRepProbThreValueChanged(app, event)
           app.ProbThreshRepNum = app.numRepProbThre.Value;
            
        end

        % Value changed function: probThreshValue
        function probThreshValueValueChanged(app, event)
            app.ProbThreshTail = app.probThreshValue.Value;
            
        end

        % Value changed function: nCheck
        function nCheckValueChanged(app, event)
            app.ProbThreshPlotChecksN = app.nCheck.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Save = uibutton(app.UIFigure, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save.Text = 'Save';
            app.setthedifferentlagvaluesinmsdefaultto101525Label = uilabel(app.UIFigure);
            app.setthedifferentlagvaluesinmsdefaultto101525Label.Text = 'set the different lag values (in ms), default to  10;15;25';
            app.LagValues = uieditfield(app.UIFigure, 'text');
            app.LagValues.ValueChangedFcn = createCallbackFcn(app, @LagValuesValueChanged, true);
            app.truncateRec = uicheckbox(app.UIFigure);
            app.truncateRec.ValueChangedFcn = createCallbackFcn(app, @truncateRecValueChanged, true);
            app.truncateRec.Text = 'Truncate Recording';
            app.InsertLenghoftruncatedrecordingLabel = uilabel(app.UIFigure);
            app.InsertLenghoftruncatedrecordingLabel.Visible = 'off';
            app.InsertLenghoftruncatedrecordingLabel.Text = 'Insert Lengh of truncated recording';
            app.TruncatedLength = uieditfield(app.UIFigure, 'numeric');
            app.TruncatedLength.ValueChangedFcn = createCallbackFcn(app, @TruncatedLengthValueChanged, true);
            app.TruncatedLength.Visible = 'off';
            app.AdjacencyMatrixTypeLabel = uilabel(app.UIFigure);
            app.AdjacencyMatrixTypeLabel.Text = 'Adjacency Matrix Type';
            app.AdjMType = uidropdown(app.UIFigure);
            app.AdjMType.Items = {'Select Option', 'weighted', 'binary'};
            app.AdjMType.ValueChangedFcn = createCallbackFcn(app, @AdjMTypeValueChanged, true);
            app.AdjMType.Value = 'Select Option';
            app.probabilisticthresholdingnumberofrepeatsLabel = uilabel(app.UIFigure);
            app.probabilisticthresholdingnumberofrepeatsLabel.Text = 'probabilistic thresholding number of repeats ';
            app.numRepProbThre = uieditfield(app.UIFigure, 'numeric');
            app.numRepProbThre.Limits = [0 300];
            app.numRepProbThre.ValueChangedFcn = createCallbackFcn(app, @numRepProbThreValueChanged, true);
            app.numRepProbThre.Value = 200;
            app.probabilisticthresholdingpercentilethresholdLabel = uilabel(app.UIFigure);
            app.probabilisticthresholdingpercentilethresholdLabel.Text = 'probabilistic thresholding percentile threshold';
            app.probThreshValue = uieditfield(app.UIFigure, 'numeric');
            app.probThreshValue.Limits = [0 1];
            app.probThreshValue.ValueChangedFcn = createCallbackFcn(app, @probThreshValueValueChanged, true);
            app.probThreshValue.Value = 0.05;
            app.ifcheck = uicheckbox(app.UIFigure);
            app.ifcheck.ValueChangedFcn = createCallbackFcn(app, @ifcheckValueChanged, true);
            app.ifcheck.Text = 'randomly sample recordings to plot probabilistic thresholding check';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.nCheck = uieditfield(app.UIFigure, 'numeric');
            app.nCheck.ValueChangedFcn = createCallbackFcn(app, @nCheckValueChanged, true);
            app.nCheck.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FuncConnOpt(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end