classdef RunMyAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SANDToolboxGuiUIFigure        matlab.ui.Figure
        Help                          matlab.ui.control.Button
        WelcometoSANDToolboxLabel     matlab.ui.control.Label
        AdvanceSettingsDropDown       matlab.ui.control.DropDown
        AdvanceSettingsDropDownLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        SaveSettingButton             matlab.ui.control.Button
        PriorAnalysisDateEditField    matlab.ui.control.EditField
        PriorAnalysisDateEditFieldLabel  matlab.ui.control.Label
        RecordingSystemDropDown       matlab.ui.control.DropDown
        RecordingSystemDropDownLabel  matlab.ui.control.Label
        RunningFromStepDropDown       matlab.ui.control.DropDown
        RunningFromStepDropDownLabel  matlab.ui.control.Label
        PrevanalysisEditField         matlab.ui.control.EditField
        PrevanalysisEditFieldLabel    matlab.ui.control.Label
        SpikeEditField                matlab.ui.control.EditField
        SpikeEditFieldLabel           matlab.ui.control.Label
        OutputEditField               matlab.ui.control.EditField
        OutputEditFieldLabel          matlab.ui.control.Label
        RawDataEditField              matlab.ui.control.EditField
        RawDataEditFieldLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        RawDataPath % Path to raw data folder
        OutPutPath  % Path to output folder
        fs = 12500; % Axion 12500 MSC 10000
        potentialDifferenceUnit = 'V'; % MEA unit measure
        PriorAnalysisDate  % Description
        channelLayout = 'Axion64'   % type of MEA system 
        priorAnalysis % Description
        startAnalysisStep % Description
        detectSpikes % Description
        PipelineStep 
        SpikeDetFold  % Description
        priorAnalysisPath % Description
        AdvThresholds =  {' '}; % threshold value for STD based spike detection, empty by default
        AdvCost = -0.1;  % Parameter for wavelet spike thresolding 
        AdvWnameList = {'bior1.5'}; %default spiking method
        AdvSpikeMethod = {'bior1p5'}; % defult method to run analysys with
        FuncConLagval = [10, 15, 25]; % set the different lag values (in ms), default to [10, 15, 25]
        TruncRec = 0; % truncate recording? 1 = yes, 0 = no
        TruncLength = 120; % length of truncated recordings (in seconds)
        adjMtype = 'weighted'; % 'weighted' or 'binary'
        ProbThreshRepNum = 200; % probabilistic thresholding number of repeats 
        ProbThreshTail = 0.05; % probabilistic thresholding percentile threshold 
        ProbThreshPlotChecks = 1; % randomly sample recordings to plot probabilistic thresholding check, 1 = yes, 0 = no
        ProbThreshPlotChecksN = 5; % number of random checks to plot


        SpikeDetOpt  % SpikeDetection parameter DIALOG APP
        FuncConnOpt  % Functional connectivity parameter DIALOG APP
    end


    methods (Access = public)
        
        function  UpdateSpikeDetParams(app, threshold, waveletmethod,cost,SpikeMethodchanged)
            app.AdvThresholds = threshold;
            app.AdvWnameList = waveletmethod;
            app.AdvCost = cost;
            app.AdvSpikeMethod = SpikeMethodchanged;
        end
        
        function  UpdateFuncConnParams(app, FuncConLagval, TruncRec, TruncLength, adjMtype, ProbThreshRepNum, ProbThreshTail, ProbThreshPlotChecks, ProbThreshPlotChecksN)
            app.FuncConLagval = FuncConLagval; % set the different lag values (in ms), default to [10, 15, 25]
            app.TruncRec = TruncRec; % truncate recording? 1 = yes, 0 = no
            app.TruncLength = TruncLength; % length of truncated recordings (in seconds)
            app.adjMtype = adjMtype; % 'weighted' or 'binary'
            app.ProbThreshRepNum = ProbThreshRepNum; % probabilistic thresholding number of repeats 
            app.ProbThreshTail = ProbThreshTail; % probabilistic thresholding percentile threshold 
            app.ProbThreshPlotChecks = ProbThreshPlotChecks; % randomly sample recordings to plot probabilistic thresholding check, 1 = yes, 0 = no
            app.ProbThreshPlotChecksN = ProbThreshPlotChecksN;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RunningFromStepDropDown
        function RunningFromStepDropDownValueChanged(app, event)
            app.PipelineStep = app.RunningFromStepDropDown.Value;
            switch app.PipelineStep
                case 'Spike Detection'
                    app.OutputEditField.Visible = "on";
                    app.SpikeEditField.Visible = "off";
                    app.PrevanalysisEditField.Visible = "off";
                    app.PriorAnalysisDateEditField.Visible = "off";
                    app.RawDataEditField.Visible = "on";
                    app.priorAnalysis = 0;
                    app.startAnalysisStep = 1;
                    app.detectSpikes = 1;

                case 'Spike and Burst Analysis'
                    app.SpikeEditField.Visible = "on";
                    app.PrevanalysisEditField.Visible = "on";
                    app.PriorAnalysisDateEditField.Visible = "on";
                    app.RawDataEditField.Visible = "off";
                    app.OutputEditField.Visible = "on";
                    app.priorAnalysis = 1;
                    app.startAnalysisStep = 2;
                    app.detectSpikes = 0;

                case 'Functional Connectivity'
                    app.SpikeEditField.Visible = "on";
                    app.PrevanalysisEditField.Visible = "on";
                    app.PriorAnalysisDateEditField.Visible = "on";
                    app.RawDataEditField.Visible = "off";
                    app.OutputEditField.Visible = "on";
                    app.priorAnalysis = 1;
                    app.startAnalysisStep = 3;
                    app.detectSpikes = 0;

                case 'Network Activity'
                    app.SpikeEditField.Visible = "on";
                    app.PrevanalysisEditField.Visible = "on";
                    app.PriorAnalysisDateEditField.Visible = "on";
                    app.RawDataEditField.Visible = "off";
                    app.OutputEditField.Visible = "on";
                    app.priorAnalysis = 1;
                    app.startAnalysisStep = 4;
                    app.detectSpikes = 0;


            end
        end

        % Button pushed function: SaveSettingButton
        function SaveSettingButtonPushed(app, event)
            
            % RawData Folder
            if isempty(app.RawDataPath) && strcmp(app.PipelineStep,'Spike Detection')
                errordlg('Please copy and paste the raw data path in the relative box','UNDEFINED RAW DATA FOLDER')
                error('UNDEFINED RAW DATA FOLDER')
            elseif  strcmp(app.PipelineStep,'Spike Detection') &&  strcmp(app.RawDataPath(1),'"')
                Params.rawData = app.RawDataPath(2:end-1) ;   % Where to save the output data, leave as '' if same as HomeDir 
            elseif strcmp(app.PipelineStep,'Spike Detection')
                Params.rawData = app.RawDataPath;
            end

            % OutputFolder
            if isempty(app.OutPutPath)
                errordlg('Please copy and paste the output data path in the relative box','UNDEFINED OUTPUTDATA FOLDER')
                error('UNDEFINED RAW DATA FOLDER')
            elseif ~isempty(app.OutPutPath) && strcmp(app.OutPutPath(1),'"') 
                Params.outputDataFolder= app.OutPutPath(2:end-1) ;   % Where to save the output data, leave as '' if same as HomeDir 
            else
                Params.outputDataFolder = app.OutPutPath ;
            
            end
            
            % Spike detected Data
            if isempty(app.SpikeDetFold) && ~strcmp(app.PipelineStep,'Spike Detection')
                errordlg('Please copy and paste the Spike detected data path in the relative box','UNDEFINED SPIKE DETECTED DATA FOLDER')
                error('UNDEFINED SPIKE DETECTED DATA FOLDER')

            elseif ~isempty(app.SpikeDetFold) && strcmp(app.SpikeDetFold(1),'"') 
                Params.spikeDetectedData  = app.SpikeDetFold(2:end-1) ;   % Where to save the output data, leave as '' if same as HomeDir 
            else
                Params.spikeDetectedData  = app.SpikeDetFold;
            
            end

            % Experimental Mat folder Data
            if isempty(app.priorAnalysisPath) && ~strcmp(app.PipelineStep,'Spike Detection')
                errordlg('Please copy and paste the priorAnalysisPath data path in the relative box','UNDEFINED priorAnalysisPath DATA FOLDER')
                error('UNDEFINED SPIKE DETECTED DATA FOLDER')
            elseif ~isempty(app.priorAnalysisPath) && strcmp(app.priorAnalysisPath(1),'"') 
                Params.priorAnalysisPath  = app.priorAnalysisPath(2:end-1) ;   % Where to save the output data, leave as '' if same as HomeDir 
            else
                Params.priorAnalysisPath  = app.priorAnalysisPath;
            
            end

            [spreadsheet,path] = uigetfile('*.csv');
            if isequal(spreadsheet,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,spreadsheet)]);
            end
            Params.SpreedsheetFolder = path;


            % Input and output filetype
            Params.spreadsheet_file_type = 'csv'; % 'csv' or 'excel'
            Params.spreadsheet_filename = spreadsheet; 
            Params.sheet = 1; % specify excel sheet
            Params.xlRange = 'A2:C40'; % specify range on the sheet (e.g., 'A2:C7' would analyse the first 6 files)
            Params.csvRange = [2, Inf]; % read the data in the range [StartRow EndRow], e.g. [2 Inf] means start reading data from row 2
            Params.output_spreadsheet_file_type = 'csv';  % .xlsx or .csv
            
            % Analysis step settings
            Params.priorAnalysisDate = app.PriorAnalysisDate ; % prior analysis date in format given in output data folder e.g., '27Sep2021'
            Params.priorAnalysis = app.priorAnalysis; % use previously analysed data? 1 = yes, 0 = no
            Params.startAnalysisStep = app.startAnalysisStep; % if Params.priorAnalysis=0, default is to start with spike detection
            Params.optionalStepsToRun = {''}; % include 'generateCSV' to generate csv for rawData folder
                                              % include 'runStats' to look at feature
                                              % correlation and classification across groups
                                              % include 'combineDIVplots' to combine plots across DIVs
            Params.optionalStepsToRun = {'combineDIVplots'};
            % Spike detection settings
            Params.detectSpikes = app.detectSpikes; % run spike detection? % 1 = yes, 0 = no
            Params.runSpikeCheckOnPrevSpikeData = 0; % whether to run spike detection check without spike detection 
            Params.fs = app.fs; % Sampling frequency, HPC: 25000, Axion: 12500;
            Params.dSampF = app.fs; % down sampling factor for spike detection check
            Params.potentialDifferenceUnit = app.potentialDifferenceUnit;  % the unit which you are recording electrical signals 
            Params.channelLayout = app.channelLayout;  % 'MCS60' or 'Axion64' or 'MCS60old'
            Params.thresholds = app.AdvThresholds; %{''}; % standard deviation multiplier threshold(s), eg. {'2.5', '3.5', '4.5'}
            Params.wnameList = app.AdvWnameList;  %{'bior1.5'}; % wavelet methods to use {'bior1.5', 'mea'}; 
            Params.costList = app.AdvCost;        %-0.1;
            Params.SpikesMethod = app.AdvSpikeMethod;    %'bior1p5';  % wavelet methods, eg. 'bior1p5', or 'mergedAll', or 'mergedWavelet'
            
            % Functional connectivity inference settings
            Params.FuncConLagval = app.FuncConLagval; % [25]; % set the different lag values (in ms), default to [10, 15, 25]
            Params.TruncRec = app.TruncRec; % truncate recording? 1 = yes, 0 = no
            Params.TruncLength = app.TruncLength; % length of truncated recordings (in seconds)
            Params.adjMtype = app.adjMtype; % 'weighted' or 'binary'
            
            % Connectivity matrix thresholding settings
            Params.ProbThreshRepNum = app.ProbThreshRepNum; % probabilistic thresholding number of repeats 
            Params.ProbThreshTail = app.ProbThreshTail; % probabilistic thresholding percentile threshold 
            Params.ProbThreshPlotChecks = app.ProbThreshPlotChecks; % randomly sample recordings to plot probabilistic thresholding check, 1 = yes, 0 = no
            Params.ProbThreshPlotChecksN = app.ProbThreshPlotChecksN ; % number of random checks to plot
            
            % Node cartography settings 
            Params.autoSetCartographyBoudariesPerLag = 1;  % whether to fit separate boundaries per lag value
            Params.cartographyLagVal = [10, 25, 50]; % lag value (ms) to use to calculate PC-Z distribution (only applies if Params.autoSetCartographyBoudariesPerLag = 0)
            Params.autoSetCartographyBoundaries = 1;  % whether to automatically determine bounds for hubs or use custom ones
            
            % Statistics and machine learning settings 
            Params.classificationTarget = 'AgeDiv';  % which property of the recordings to classify 
            Params.classification_models = {'linearSVM', 'kNN', 'fforwardNN', 'decisionTree', 'LDA'};
            Params.regression_models = {'svmRegressor', 'regressionTree', 'ridgeRegression', 'fforwardNN'};
            
            % Plot settings
            Params.figExt = {'.png', '.svg'};  % supported options are '.fig', '.png', and '.svg'
            Params.fullSVG = 1;  % whether to insist svg even with plots with large number of elements
            Params.showOneFig = 1;  % otherwise, 0 = pipeline shows plots as it runs, 1: supress plots
            
            
            save('AnalysisSettings','Params')
            fig = uifigure;
            message = {'Setting Saved!','You can run the pipeline!'};
            uialert(fig,message,'Success',...
            'Icon','success');
        end

        % Value changed function: RawDataEditField
        function RawDataEditFieldValueChanged(app, event)
            app.RawDataPath = app.RawDataEditField.Value;
            
        end

        % Value changed function: OutputEditField
        function OutputEditFieldValueChanged(app, event)
            app.OutPutPath = app.OutputEditField.Value;
            
        end

        % Value changed function: RecordingSystemDropDown
        function RecordingSystemDropDownValueChanged(app, event)
            RecordingSystem = app.RecordingSystemDropDown.Value;
            switch RecordingSystem
                case 'Axion64'

                    app.fs = 12500;
                    app.potentialDifferenceUnit = 'V';
                    app.channelLayout = RecordingSystem;

                case 'MSC60'

                    app.fs = 10000;
                    app.potentialDifferenceUnit = 'uV';
                    app.channelLayout = RecordingSystem;


            end
        end

        % Value changed function: PriorAnalysisDateEditField
        function PriorAnalysisDateEditFieldValueChanged(app, event)
            app.PriorAnalysisDate = app.PriorAnalysisDateEditField.Value;
            
        end

        % Value changed function: SpikeEditField
        function SpikeEditFieldValueChanged(app, event)
            app.SpikeDetFold = app.SpikeEditField.Value;
            
        end

        % Value changed function: PrevanalysisEditField
        function PrevanalysisEditFieldValueChanged(app, event)
            app.priorAnalysisPath = app.PrevanalysisEditField.Value;
            
        end

        % Value changed function: AdvanceSettingsDropDown
        function AdvanceSettingsDropDownValueChanged(app, event)
            app2run = app.AdvanceSettingsDropDown.Value;
            
            switch app2run
                case  'Spike Detection'
                       app.SpikeDetOpt = SpikeDetOpt(app);
                case  'Functional connectivity'
                       app.FuncConnOpt = FuncConnOpt(app);
                case  'Connectivity matrix thresholding'


            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SANDToolboxGuiUIFigure = uifigure('Visible', 'off');
            app.SANDToolboxGuiUIFigure.Name = 'SAND Toolbox Gui';
            app.SANDToolboxGuiUIFigure.Icon = fullfile(pathToMLAPP, 'logo.png');
            app.RawDataEditFieldLabel = uilabel(app.SANDToolboxGuiUIFigure);
            app.RawDataEditFieldLabel.Text = 'Raw Data';
            app.RawDataEditField = uieditfield(app.SANDToolboxGuiUIFigure, 'text');
            app.RawDataEditField.ValueChangedFcn = createCallbackFcn(app, @RawDataEditFieldValueChanged, true);
            app.RawDataEditField.Value = 'Copy and paste the Raw Data folder path here';
            app.OutputEditFieldLabel = uilabel(app.SANDToolboxGuiUIFigure);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.SANDToolboxGuiUIFigure, 'text');
            app.OutputEditField.ValueChangedFcn = createCallbackFcn(app, @OutputEditFieldValueChanged, true);
            app.OutputEditField.Value = 'Copy and paste the output folder path here';
            app.SpikeEditFieldLabel = uilabel(app.SANDToolboxGuiUIFigure);
            app.SpikeEditFieldLabel.Text = 'Spike ';
            app.SpikeEditField = uieditfield(app.SANDToolboxGuiUIFigure, 'text');
            app.SpikeEditField.ValueChangedFcn = createCallbackFcn(app, @SpikeEditFieldValueChanged, true);
            app.SpikeEditField.Value = 'Copy and paste the Spike detected file folder folder path here';
            app.PrevanalysisEditFieldLabel = uilabel(app.SANDToolboxGuiUIFigure);
            app.PrevanalysisEditFieldLabel.Text = 'Prev analysis';
            app.PrevanalysisEditField = uieditfield(app.SANDToolboxGuiUIFigure, 'text');
            app.PrevanalysisEditField.ValueChangedFcn = createCallbackFcn(app, @PrevanalysisEditFieldValueChanged, true);
            app.PrevanalysisEditField.Value = 'Copy and paste the Prior Analysis folder folder path here';
            app.RunningFromStepDropDownLabel = uilabel(app.SANDToolboxGuiUIFigure);
            app.RunningFromStepDropDownLabel.Text = 'Running From Step:';
            app.RunningFromStepDropDown = uidropdown(app.SANDToolboxGuiUIFigure);
            app.RunningFromStepDropDown.Items = {'Select Step', 'Spike Detection', 'Spike and Burst Analysis', 'Functional Connectivity', 'Network Activity'};
            app.RunningFromStepDropDown.ItemsData = {'Select Step', 'Spike Detection', 'Spike and Burst Analysis', 'Functional Connectivity', 'Network Activity'};
            app.RunningFromStepDropDown.ValueChangedFcn = createCallbackFcn(app, @RunningFromStepDropDownValueChanged, true);
            app.RunningFromStepDropDown.Value = 'Select Step';
            app.RecordingSystemDropDownLabel = uilabel(app.SANDToolboxGuiUIFigure);
            app.RecordingSystemDropDownLabel.Text = 'Recording System';
            app.RecordingSystemDropDown = uidropdown(app.SANDToolboxGuiUIFigure);
            app.RecordingSystemDropDown.Items = {'Select system', 'Axion64', 'MSC60'};
            app.RecordingSystemDropDown.ValueChangedFcn = createCallbackFcn(app, @RecordingSystemDropDownValueChanged, true);
            app.RecordingSystemDropDown.Value = 'Select system';
            app.PriorAnalysisDateEditFieldLabel = uilabel(app.SANDToolboxGuiUIFigure);
            app.PriorAnalysisDateEditFieldLabel.Text = 'Prior Analysis Date';
            app.PriorAnalysisDateEditField = uieditfield(app.SANDToolboxGuiUIFigure, 'text');
            app.PriorAnalysisDateEditField.ValueChangedFcn = createCallbackFcn(app, @PriorAnalysisDateEditFieldValueChanged, true);
            app.PriorAnalysisDateEditField.Value = '01Jen2023';
            app.SaveSettingButton = uibutton(app.SANDToolboxGuiUIFigure, 'push');
            app.SaveSettingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingButtonPushed, true);
            app.SaveSettingButton.Text = 'Save Setting';
            app.Image = uiimage(app.SANDToolboxGuiUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.AdvanceSettingsDropDownLabel = uilabel(app.SANDToolboxGuiUIFigure);
            app.AdvanceSettingsDropDownLabel.Text = 'Advance Settings';
            app.AdvanceSettingsDropDown = uidropdown(app.SANDToolboxGuiUIFigure);
            app.AdvanceSettingsDropDown.Items = {'Select Setting', 'Spike Detection', 'Functional connectivity', 'BiAdvancedSetting', 'OptionalStep2Run'};
            app.AdvanceSettingsDropDown.ValueChangedFcn = createCallbackFcn(app, @AdvanceSettingsDropDownValueChanged, true);
            app.AdvanceSettingsDropDown.Value = 'Select Setting';
            app.WelcometoSANDToolboxLabel = uilabel(app.SANDToolboxGuiUIFigure);
            app.WelcometoSANDToolboxLabel.Text = {'Welcome to SAND Toolbox!'; 'Here you can set up the parameter required for your analysis'; ''; 'Please follow the example format!'};
            app.Help = uibutton(app.SANDToolboxGuiUIFigure, 'push');
            app.Help.Text = 'Help';
            % Show the figure after all components are created
            app.SANDToolboxGuiUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RunMyAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SANDToolboxGuiUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SANDToolboxGuiUIFigure)
        end
    end
end