classdef SpikeDetOpt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Cost                           matlab.ui.control.NumericEditField
        CostLabel                      matlab.ui.control.Label
        close                          matlab.ui.control.Button
        Title                          matlab.ui.control.Label
        SpikeMethod                    matlab.ui.control.DropDown
        SpikeMethodtorunanalysisLabel  matlab.ui.control.Label
        exbior15bior13meaLabel         matlab.ui.control.Label
        ex354555Label                  matlab.ui.control.Label
        WaveletMethods                 matlab.ui.control.EditField
        WaveletMethodsLabel            matlab.ui.control.Label
        Threshold                      matlab.ui.control.EditField
        STDthresholdvaluesLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp    % Main apprty 

        ThresholdCell = {' '};
        WaveletMethodCell = {'bior1.5'};
        cost = -0.10;
        SpikeMethodchanged = {'bior1p5'};
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, RunMyAnalysis)
            % Store main app object
            app.CallingApp = RunMyAnalysis;      

        end

        % Button pushed function: close
        function closeButtonPushed(app, event)
            UpdateSpikeDetParams(app.CallingApp, app.ThresholdCell, app.WaveletMethodCell, app.cost, app.SpikeMethod.Value)
            delete(app)
        end

        % Value changed function: Threshold
        function ThresholdValueChanged(app, event)
            txt = app.Threshold.Value;
            app.ThresholdCell = split(txt,';');
        end

        % Value changed function: WaveletMethods
        function WaveletMethodsValueChanged(app, event)
            app.WaveletMethodCell = split(app.WaveletMethods.Value,';');            
        end

        % Value changed function: Cost
        function CostValueChanged(app, event)
            app.cost = app.Cost.Value;            
        end

        % Value changed function: SpikeMethod
        function SpikeMethodValueChanged(app, event)
            app.SpikeMethodchanged = app.SpikeMethod.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'MATLAB App';
            app.STDthresholdvaluesLabel = uilabel(app.UIFigure);
            app.STDthresholdvaluesLabel.Text = 'STD threshold values';
            app.Threshold = uieditfield(app.UIFigure, 'text');
            app.Threshold.ValueChangedFcn = createCallbackFcn(app, @ThresholdValueChanged, true);
            app.WaveletMethodsLabel = uilabel(app.UIFigure);
            app.WaveletMethodsLabel.Text = 'Wavelet Methods';
            app.WaveletMethods = uieditfield(app.UIFigure, 'text');
            app.WaveletMethods.ValueChangedFcn = createCallbackFcn(app, @WaveletMethodsValueChanged, true);
            app.ex354555Label = uilabel(app.UIFigure);
            app.ex354555Label.Text = 'ex: 3.5;4.5;5.5';
            app.exbior15bior13meaLabel = uilabel(app.UIFigure);
            app.exbior15bior13meaLabel.Text = 'ex: bior1.5;bior1.3;mea';
            app.SpikeMethodtorunanalysisLabel = uilabel(app.UIFigure);
            app.SpikeMethodtorunanalysisLabel.Text = 'Spike Method to run analysis';
            app.SpikeMethod = uidropdown(app.UIFigure);
            app.SpikeMethod.Items = {'bior1p5', 'Select Option', 'mea', 'bior1p3', 'thr3p5', 'thr4p5'};
            app.SpikeMethod.ValueChangedFcn = createCallbackFcn(app, @SpikeMethodValueChanged, true);
            app.SpikeMethod.Value = 'Select Option';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = {'Selected the preferred option for spike detection, '; ''; 'follow the example format'};
            app.close = uibutton(app.UIFigure, 'push');
            app.close.ButtonPushedFcn = createCallbackFcn(app, @closeButtonPushed, true);
            app.close.Text = 'Save';
            app.CostLabel = uilabel(app.UIFigure);
            app.CostLabel.Text = 'Cost';
            app.Cost = uieditfield(app.UIFigure, 'numeric');
            app.Cost.Limits = [-1 0];
            app.Cost.ValueChangedFcn = createCallbackFcn(app, @CostValueChanged, true);
            app.Cost.Value = -0.12;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpikeDetOpt(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end